classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VocPlayerUIFigure            matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadFileMenu                 matlab.ui.container.Menu
        ExportFileMenu               matlab.ui.container.Menu
        ProjectMenu                  matlab.ui.container.Menu
        SaveProjectMenu              matlab.ui.container.Menu
        OpenProjectMenu              matlab.ui.container.Menu
        AdditionalCommentsTextArea   matlab.ui.control.TextArea
        AdditionalCommentsTextAreaLabel  matlab.ui.control.Label
        ChildWordButtonGroup         matlab.ui.container.ButtonGroup
        NoButton_2                   matlab.ui.control.RadioButton
        YesButton_2                  matlab.ui.control.RadioButton
        ChildVoiceButtonGroup        matlab.ui.container.ButtonGroup
        NoButton                     matlab.ui.control.RadioButton
        YesButton                    matlab.ui.control.RadioButton
        PlayVocalizationButton       matlab.ui.control.Button
        WhatdidyouhearTextArea       matlab.ui.control.TextArea
        WhatdidyouhearTextAreaLabel  matlab.ui.control.Label
        NextVocButton                matlab.ui.control.Button
        PrevVocButton                matlab.ui.control.Button
        UIVocTable                   matlab.ui.control.Table
    end

    properties (SetObservable, Access = private)
        CodingTable % Lists the vocalization numbers, codes, etc
    end
    properties (Access = private)
        filename
        filepath
        Counter  % Global counter, what voc are we on?
        Fs = 44100; %Default fs :D
        MatFile
        NumVocs

        hasbeenPlayed % Logical vector for each vocalization. Has it been played yet?
        hasbeenloadedonce = 0;
        %UI Table styles
        uncodedStyle
        codedStyle
    end
    
    methods (Access = private)
        
        function InitializeVocalizations(app,isLoadingProject)
            if nargin < 2
                isLoadingProject = 0;
            end


            if ~isLoadingProject % If it isn't a project being loaded, initialize the table.
            % Creates the codingtable property, initializes the UIlist of the vocalizations
                app.NumVocs = size(app.MatFile.vocStore,2);
                app.CodingTable = table('VariableNames',{'Vocalization','Voice','Word','Transcript','Comments'},... %Word and Voice are doubles but act as logicals, that way they can be initalized with NaN
                                        'VariableTypes',{'double','double','double','string','string'},...
                                        'size',[app.NumVocs,5]);
                app.CodingTable.Vocalization = [1:app.NumVocs]';
                app.CodingTable.Transcript = repelem("",app.NumVocs,1);
                app.CodingTable.Comments = repelem("",app.NumVocs,1);
                % Initialize hasbeenplayed array
                app.hasbeenPlayed = repelem(logical(0),1,app.NumVocs);
            end
            app.NumVocs = size(app.MatFile.vocStore,2);

            % Set up the styles for each 
            app.uncodedStyle = uistyle("BackgroundColor","yellow");
            app.codedStyle = uistyle("BackgroundColor","green");
            addStyle(app.UIVocTable,app.uncodedStyle,'row',1:app.NumVocs);
            
           % Set up the listener
            addlistener(app, 'CodingTable', 'PostSet', @(src, event) updateUITable(app));
            
        
            % Initialize the UI List
            app.UIVocTable.Data = app.CodingTable;

            % If the project was being loaded, change the styles
            % accordingly for the ones that have already been listened to
            if isLoadingProject
                app.checkHasPlayed();
            end

        end
        
        function CellSelected(app,src,ev)
            app.Counter = ev.DisplayIndices(1,1);
            app.setData;
            app.PopulateBoxes;
        end

        function LoadMatFile(app)
            [file,path] = uigetfile("*.mat");
            if file
            app.filename = file;
            app.filepath = strcat(path,file);

            app.MatFile = load(app.filepath);
            
            % Make sure that the vocstore section of the matfile is a row
            % vector
            if size(app.MatFile.vocStore,1) ~= 1
                app.MatFile.vocStore = app.MatFile.vocStore';
            end
            % Initialize.
            app.InitializeVocalizations;

            % Reset the counter.
            app.Counter =1;
            end
        end

        
        function checkHasPlayed(app)
            % Checks what has already been played and resets the styles for
            % ALL. Used for when the project is being loaded.
            ix = find(app.hasbeenPlayed == 1);
            addStyle(app.UIVocTable, app.codedStyle,'row',ix);

        end
        

        function setData(app)
            app.CodingTable(app.Counter,2:end) = {app.YesButton.Value, app.YesButton_2.Value, app.formatText(app.WhatdidyouhearTextArea.Value), app.formatText(app.AdditionalCommentsTextArea.Value)};
            if app.hasbeenPlayed(app.Counter)
                addStyle(app.UIVocTable,app.codedStyle,'row',app.Counter);
            end
        end

        function PopulateBoxes(app)
            data = app.CodingTable(app.Counter,:);

            if data.Voice == 1
                app.YesButton.Value = 1;
            else
                app.YesButton.Value = 0;
            end

            if data.Word == 1
                app.YesButton_2.Value = 1;
            else
                app.YesButton_2.Value = 0;
            end

            app.WhatdidyouhearTextArea.Value = data.Transcript;
            app.AdditionalCommentsTextArea.Value = data.Comments;

        end


        function str = formatText(app,ch)
            str = string(ch{1});
            for i = 2:size(ch,1)
                str = str + " " + string(ch{i});
            end
        end


        function StartupInitializations(app)
            app.UIVocTable.CellSelectionCallback = @app.CellSelected;
        end
        function updateUITable(app)
            app.UIVocTable.Data = app.CodingTable;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartupInitializations;
        end

        % Menu selected function: LoadFileMenu
        function LoadFileMenuSelected(app, event)
            if app.hasbeenloadedonce
                % app.LoadMatFile
                [choice] = uiconfirm(app.VocPlayerUIFigure,"Maya says - 'Have you saved the data? Make sure you save before loading another file!'","Warning!",'Icon','./imgs/maya2.png');
                if ~strcmp(choice,'Cancel')
                    app.LoadMatFile;
                end
            else
                app.LoadMatFile;
            end
            
            % Reset Counter
            app.Counter = 1;
            
            app.hasbeenloadedonce = 1;
        end

        % Button pushed function: NextVocButton
        function NextVocButtonPushed(app, event)
            % Write the data from boxes to the table
            % app.CodingTable(app.Counter,2:end) = {app.YesButton.Value, app.YesButton_2.Value, app.formatText(app.WhatdidyouhearTextArea.Value)};
            app.setData;
            if app.Counter == app.NumVocs
                app.Counter = app.NumVocs;
            else
                app.Counter = app.Counter + 1;
            end

            % Update the table selection
            app.UIVocTable.Selection = app.Counter;
            % Populate
            app.PopulateBoxes;
        end

        % Button pushed function: PrevVocButton
        function PrevVocButtonPushed(app, event)
            % Write the data from boxes to the table
            % app.CodingTable(app.Counter,2:end) = {app.YesButton.Value, app.YesButton_2.Value, app.formatText(app.WhatdidyouhearTextArea.Value)};
            app.setData;
            if app.Counter <= 1
                app.Counter = 1;
            else
                app.Counter = app.Counter - 1;
            end

            % Update the table selection
            app.UIVocTable.Selection = app.Counter;
            % Populate
            app.PopulateBoxes;
        end

        % Button pushed function: PlayVocalizationButton
        function PlayVocalizationButtonPushed(app, event)
            soundsc(app.MatFile.vocStore{app.Counter},app.Fs);
            % set the status of the vocalization to has been played
            app.hasbeenPlayed(app.Counter) = 1;
        end

        % Menu selected function: ExportFileMenu
        function ExportFileMenuSelected(app, event)
            path = uigetdir();
            if path
                % Create a new table to append to the coding table from the
                % legend portion of the app.MatFile
                % leg = array2table(app.MatFile.leg,'VariableNames',{'FileNum','SubID','Vocalization Number'});
                % tb = [app.CodingTable,leg];
                tb = app.CodingTable;
                tb.START = app.MatFile.startIX;
                tb.STOP = app.MatFile.stopIX;
                writetable(tb,strcat(path,'/',app.filename(1:end-4),'_coded.csv'));
            end
        end

        % Menu selected function: SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
            path = uigetdir();
            if path
                % Write a table with everything in it
                tb = app.CodingTable;
                tb.HasPlayed = app.hasbeenPlayed(:);
                matfile = app.MatFile;
                save(strcat(path,'/',app.filename(1:end-4),'_UNFINISHED_PROJECT.mat'),'tb','matfile');
            end
        end

        % Menu selected function: OpenProjectMenu
        function OpenProjectMenuSelected(app, event)
            [path,file] = uigetfile('*.mat');
            if file
                % tb = readtable(strcat(file,path));
                tmp = load(strcat(file,path));
                app.CodingTable = tmp.tb(:,1:end-1);
                app.hasbeenPlayed = tmp.tb.HasPlayed;
                app.MatFile = tmp.matfile;
                
                app.InitializeVocalizations(1);

                % Set counter to current selection
                selectedRow = app.UIVocTable.Selection;
                if isnumeric(selectedRow)
                    app.Counter = selectedRow;
                else
                    app.Counter = 1;
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VocPlayerUIFigure = uifigure('Visible', 'off');
            app.VocPlayerUIFigure.Name = 'VocPlayer';
            app.FileMenu = uimenu(app.VocPlayerUIFigure);
            app.FileMenu.Text = 'File...';
            app.LoadFileMenu = uimenu(app.FileMenu);
            app.LoadFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFileMenuSelected, true);
            app.LoadFileMenu.Text = 'Load File';
            app.ExportFileMenu = uimenu(app.FileMenu);
            app.ExportFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportFileMenuSelected, true);
            app.ExportFileMenu.Text = 'Export File';
            app.ProjectMenu = uimenu(app.FileMenu);
            app.ProjectMenu.Text = 'Project...';
            app.SaveProjectMenu = uimenu(app.ProjectMenu);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Text = 'Save Project';
            app.OpenProjectMenu = uimenu(app.ProjectMenu);
            app.OpenProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenProjectMenuSelected, true);
            app.OpenProjectMenu.Text = 'Open Project';
            app.UIVocTable = uitable(app.VocPlayerUIFigure);
            app.UIVocTable.ColumnName = {'Vocalization'; 'Voice'; 'Word'; 'Transcript'; 'Comments'};
            app.UIVocTable.RowName = {};
            app.UIVocTable.SelectionType = 'row';
            app.UIVocTable.Multiselect = 'off';
            app.PrevVocButton = uibutton(app.VocPlayerUIFigure, 'push');
            app.PrevVocButton.ButtonPushedFcn = createCallbackFcn(app, @PrevVocButtonPushed, true);
            app.PrevVocButton.Text = 'Prev Voc';
            app.NextVocButton = uibutton(app.VocPlayerUIFigure, 'push');
            app.NextVocButton.ButtonPushedFcn = createCallbackFcn(app, @NextVocButtonPushed, true);
            app.NextVocButton.Text = 'Next Voc';
            app.WhatdidyouhearTextAreaLabel = uilabel(app.VocPlayerUIFigure);
            app.WhatdidyouhearTextAreaLabel.Text = 'What did you hear?';
            app.WhatdidyouhearTextArea = uitextarea(app.VocPlayerUIFigure);
            app.PlayVocalizationButton = uibutton(app.VocPlayerUIFigure, 'push');
            app.PlayVocalizationButton.ButtonPushedFcn = createCallbackFcn(app, @PlayVocalizationButtonPushed, true);
            app.PlayVocalizationButton.Text = 'Play Vocalization';
            app.ChildVoiceButtonGroup = uibuttongroup(app.VocPlayerUIFigure);
            app.ChildVoiceButtonGroup.Title = 'Did you hear a child''s voice?';
            app.YesButton = uiradiobutton(app.ChildVoiceButtonGroup);
            app.YesButton.Text = 'Yes';
            app.NoButton = uiradiobutton(app.ChildVoiceButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.ChildWordButtonGroup = uibuttongroup(app.VocPlayerUIFigure);
            app.ChildWordButtonGroup.Title = 'Did the child say a word?';
            app.YesButton_2 = uiradiobutton(app.ChildWordButtonGroup);
            app.YesButton_2.Text = 'Yes';
            app.NoButton_2 = uiradiobutton(app.ChildWordButtonGroup);
            app.NoButton_2.Text = 'No';
            app.NoButton_2.Value = true;
            app.AdditionalCommentsTextAreaLabel = uilabel(app.VocPlayerUIFigure);
            app.AdditionalCommentsTextAreaLabel.Text = 'Additional Comments';
            app.AdditionalCommentsTextArea = uitextarea(app.VocPlayerUIFigure);
            % Show the figure after all components are created
            app.VocPlayerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VocPlayerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VocPlayerUIFigure)
        end
    end
end